classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        TabGroup                     matlab.ui.container.TabGroup
        HomeTab                      matlab.ui.container.Tab
        Panel                        matlab.ui.container.Panel
        SelectMethodDropDownLabel    matlab.ui.control.Label
        SelectMethodDropDown         matlab.ui.control.DropDown
        FunctionIsEditFieldLabel     matlab.ui.control.Label
        FunctionIsEditField          matlab.ui.control.EditField
        X0EditFieldLabel             matlab.ui.control.Label
        X0EditField                  matlab.ui.control.NumericEditField
        X1EditFieldLabel             matlab.ui.control.Label
        X1EditField                  matlab.ui.control.NumericEditField
        EditFieldLabel               matlab.ui.control.Label
        maxErrorEditField            matlab.ui.control.NumericEditField
        MaxIterationsEditFieldLabel  matlab.ui.control.Label
        MaxIterationsEditField       matlab.ui.control.NumericEditField
        ModeSwitchLabel              matlab.ui.control.Label
        ModeSwitch                   matlab.ui.control.Switch
        CalculateButton              matlab.ui.control.Button
        selectfileButton             matlab.ui.control.Button
        UIAxes                       matlab.ui.control.UIAxes
        InfoTab                      matlab.ui.container.Tab
        UITable                      matlab.ui.control.Table
        fileButton                   matlab.ui.control.Button
    end

    properties (Access = public)
        currentIteration = 0;
        value = 0;
    end

    methods (Access = private)

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            f = str2func(['@(x)' app.FunctionIsEditField.Value]);
            x0 = app.X0EditField.Value;
            x1 = app.X1EditField.Value;
            maxError = app.maxErrorEditField.Value;
            maxItr = app.MaxIterationsEditField.Value;
            mode = app.ModeSwitch.Value;


            fplot(app.UIAxes,f);
            
            switch (app.SelectMethodDropDown.Value)
                case 'Bisection'
                    
                case 'False-Position'
                    
                case 'Fixed Point'
                    [root, i, percision, time] = FPI(f, app.currentIteration, x0, maxError, maxItr, mode);
                    app.X0EditField.Value = root;
                    app.currentIteration = i;
                case 'Newton-Raphson'
                            
                case 'Secant'
                                
                case 'Bierge Vieta'   
            end
            
        end

        % Value changed function: FunctionIsEditField
        function FunctionIsEditFieldValueChanged(app, event)
            value = app.FunctionIsEditField.Value;
            app.X0EditField.Value = 0;
            app.currentIteration = 0;
            app.MaxIterationsEditField.value = 50;
            
        end

        % Value changing function: FunctionIsEditField
        function FunctionIsEditFieldValueChanging(app, event)
            changingValue = event.Value;
            x=app.FunctionIsEditField.v
            app.FunctionIsEditField.value='x'; 
        end

        % Value changed function: X0EditField
        function X0EditFieldValueChanged(app, event)
            value = app.X0EditField.Value;
            app.currentIteration = 0;
        end

        % Button pushed function: selectfileButton
        function selectfileButtonPushed(app, event)
         [filename pathname] = uigetfile({'*.txt'},'File Selector');
         allpath =strcat(pathname,filename);
         fileID = fopen(allpath,'r');
         C = textscan(fileID,'%s %s %s','Delimiter','|');
         fclose(fileID);
         %myCell = C{1}(:,1);
         app.X0EditField.Value = str2double(C{2}{1});
        app.FunctionIsEditField.Value= C{2}{2};
        %value =  app.FunctionIsEditField.Value;
        % app.MaxIterationsEditField.value = str2double(C{2}{5});
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.HomeTab = uitab(app.TabGroup);
            app.HomeTab.Title = 'Home';
            app.Panel = uipanel(app.HomeTab);
            app.SelectMethodDropDownLabel = uilabel(app.Panel);
            app.SelectMethodDropDownLabel.Text = 'Select Method';
            app.SelectMethodDropDown = uidropdown(app.Panel);
            app.SelectMethodDropDown.Items = {'Bisection', 'False-Position', 'Fixed Point', 'Newton-Raphson', 'Secant', 'Bierge Vieta'};
            app.SelectMethodDropDown.Value = 'Fixed Point';
            app.FunctionIsEditFieldLabel = uilabel(app.Panel);
            app.FunctionIsEditFieldLabel.Text = 'Function Is';
            app.FunctionIsEditField = uieditfield(app.Panel, 'text');
            app.FunctionIsEditField.ValueChangedFcn = createCallbackFcn(app, @FunctionIsEditFieldValueChanged, true);
            app.FunctionIsEditField.ValueChangingFcn = createCallbackFcn(app, @FunctionIsEditFieldValueChanging, true);
            app.FunctionIsEditField.Value = 'x^2-1';
            app.X0EditFieldLabel = uilabel(app.Panel);
            app.X0EditFieldLabel.Text = 'X0';
            app.X0EditField = uieditfield(app.Panel, 'numeric');
            app.X0EditField.ValueChangedFcn = createCallbackFcn(app, @X0EditFieldValueChanged, true);
            app.X1EditFieldLabel = uilabel(app.Panel);
            app.X1EditFieldLabel.Text = 'X1';
            app.X1EditField = uieditfield(app.Panel, 'numeric');
            app.EditFieldLabel = uilabel(app.Panel);
            app.EditFieldLabel.Text = 'ɛ';
            app.maxErrorEditField = uieditfield(app.Panel, 'numeric');
            app.maxErrorEditField.Value = 0.0001;
            app.MaxIterationsEditFieldLabel = uilabel(app.Panel);
            app.MaxIterationsEditFieldLabel.Text = 'Max Iterations';
            app.MaxIterationsEditField = uieditfield(app.Panel, 'numeric');
            app.MaxIterationsEditField.Limits = [0 Inf];
            app.MaxIterationsEditField.Value = 50;
            app.ModeSwitchLabel = uilabel(app.Panel);
            app.ModeSwitchLabel.Text = 'Mode';
            app.ModeSwitch = uiswitch(app.Panel, 'slider');
            app.ModeSwitch.Items = {'Quick', 'Step'};
            app.ModeSwitch.Value = 'Quick';
            app.CalculateButton = uibutton(app.Panel, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.selectfileButton = uibutton(app.Panel, 'push');
            app.selectfileButton.ButtonPushedFcn = createCallbackFcn(app, @selectfileButtonPushed, true);
            app.selectfileButton.Text = 'select file';
            app.UIAxes = uiaxes(app.HomeTab);
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.UIAxes.XLim = [-100 100];
            app.UIAxes.XLimMode = 'manual';
            app.UIAxes.YLim = [-100 100];
            app.UIAxes.YLimMode = 'manual';
            app.UIAxes.XAxisLocation = 'origin';
            app.UIAxes.YAxisLocation = 'origin';
            app.UIAxes.YTick = [-100 -80 -60 -40 -20 0 20 40 60 80 100];
            app.UIAxes.YTickLabel = {''; '-80'; '-60'; '-40'; '-20'; '0'; '20'; '40'; '60'; '80'; ''};
            app.InfoTab = uitab(app.TabGroup);
            app.InfoTab.Title = 'Info';
            app.UITable = uitable(app.InfoTab);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            app.fileButton = uibutton(app.UIFigure, 'push');
            app.fileButton.Text = 'file';
        end
    end

    methods (Access = public)

        % Construct app
        function app = app1

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end