classdef interpolation < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        InterpolationUIFigure      matlab.ui.Figure
        TabGroup                   matlab.ui.container.TabGroup
        HomeTab                    matlab.ui.container.Tab
        Panel                      matlab.ui.container.Panel
        SelectMethodDropDownLabel  matlab.ui.control.Label
        SelectMethodDropDown       matlab.ui.control.DropDown
        OrderEditFieldLabel        matlab.ui.control.Label
        OrderEditField             matlab.ui.control.EditField
        XEditFieldLabel            matlab.ui.control.Label
        XEditField                 matlab.ui.control.NumericEditField
        YEditFieldLabel            matlab.ui.control.Label
        YEditField                 matlab.ui.control.NumericEditField
        CalculateButton            matlab.ui.control.Button
        selectfileButton           matlab.ui.control.Button
        EnterasamplepointLabel     matlab.ui.control.Label
        EnteraquerypointLabel      matlab.ui.control.Label
        XEditField_2Label          matlab.ui.control.Label
        XEditField_2               matlab.ui.control.NumericEditField
        AddButton                  matlab.ui.control.Button
        AddButton_2                matlab.ui.control.Button
        CalculateButton_2          matlab.ui.control.Button
        UIAxes                     matlab.ui.control.UIAxes
        PolyText                   matlab.ui.control.TextArea
        ResultText                 matlab.ui.control.TextArea
    end


    properties (Access = public)
        xs = [];
        ys = [];
        order = 4;
        points = [];
    end


    methods (Access = private)

        % Value changed function: SelectMethodDropDown
        function SelectMethodDropDownValueChanged(app, event)
            value = app.SelectMethodDropDown.Value;
            
        end

        % Button pushed function: AddButton
        function AddButtonPushed(app, event)
            x = app.XEditField.Value;
            y = app.YEditField.Value;
            app.xs(length(app.xs)+1) = x;
            app.ys(length(app.ys)+1) = y;
        end

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            app.order = app.OrderEditField.Value;
            switch (app.SelectMethodDropDown.Value)
                case 'Newton'
                    [poly, result] = NewtonInterpolation(app.order, app.points, app.xs, app.ys);
                    app.PolyText.Text = poly; % to do: add time
                    app.ResultText.Text = result;
                case 'Lagrange'
                    [poly, result] = lagrange(app.order, app.points, app.xs, app.ys);
                    app.PolyText.Text = poly; % to do: add time
                    app.ResultText.Text = result;
            end
                    
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.InterpolationUIFigure = uifigure;
            app.InterpolationUIFigure.Name = 'Interpolation';
            app.TabGroup = uitabgroup(app.InterpolationUIFigure);
            app.HomeTab = uitab(app.TabGroup);
            app.HomeTab.Title = 'Home';
            app.Panel = uipanel(app.HomeTab);
            app.SelectMethodDropDownLabel = uilabel(app.Panel);
            app.SelectMethodDropDownLabel.Text = 'Select Method';
            app.SelectMethodDropDown = uidropdown(app.Panel);
            app.SelectMethodDropDown.Items = {'Newton', 'Lagrange'};
            app.SelectMethodDropDown.ValueChangedFcn = createCallbackFcn(app, @SelectMethodDropDownValueChanged, true);
            app.SelectMethodDropDown.Value = 'Newton';
            app.OrderEditFieldLabel = uilabel(app.Panel);
            app.OrderEditFieldLabel.Text = 'Order';
            app.OrderEditField = uieditfield(app.Panel, 'text');
            app.OrderEditField.Value = '4';
            app.XEditFieldLabel = uilabel(app.Panel);
            app.XEditFieldLabel.Text = 'X';
            app.XEditField = uieditfield(app.Panel, 'numeric');
            app.YEditFieldLabel = uilabel(app.Panel);
            app.YEditFieldLabel.Text = 'Y';
            app.YEditField = uieditfield(app.Panel, 'numeric');
            app.CalculateButton = uibutton(app.Panel, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.selectfileButton = uibutton(app.Panel, 'push');
            app.selectfileButton.Text = 'select file';
            app.EnterasamplepointLabel = uilabel(app.Panel);
            app.EnterasamplepointLabel.Text = 'Enter a sample point';
            app.EnteraquerypointLabel = uilabel(app.Panel);
            app.EnteraquerypointLabel.Text = 'Enter a query point';
            app.XEditField_2Label = uilabel(app.Panel);
            app.XEditField_2Label.Text = 'X';
            app.XEditField_2 = uieditfield(app.Panel, 'numeric');
            app.AddButton = uibutton(app.Panel, 'push');
            app.AddButton.ButtonPushedFcn = createCallbackFcn(app, @AddButtonPushed, true);
            app.AddButton.Text = 'Add';
            app.AddButton_2 = uibutton(app.Panel, 'push');
            app.AddButton_2.Text = 'Add';
            app.CalculateButton_2 = uibutton(app.Panel, 'push');
            app.CalculateButton_2.Text = 'Calculate';
            app.UIAxes = uiaxes(app.HomeTab);
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.UIAxes.XLim = [-100 100];
            app.UIAxes.YLim = [-100 100];
            app.UIAxes.ZLim = [0 1];
            app.UIAxes.CLim = [0 1];
            app.UIAxes.XAxisLocation = 'origin';
            app.UIAxes.XTick = [-100 -50 0 50 100];
            app.UIAxes.YAxisLocation = 'origin';
            app.UIAxes.YTick = [-100 -50 0 50 100];
            app.UIAxes.YTickLabel = {''; '-50'; ''; '50'; ''};
            app.UIAxes.ZTick = [0 0.5 1];
            app.PolyText = uitextarea(app.HomeTab);
            app.PolyText.Value = {'Polynomial function:'; 'Time:'};
            app.ResultText = uitextarea(app.HomeTab);
            app.ResultText.Value = {'Query point:'};
        end
    end

    methods (Access = public)

        % Construct app
        function app = interpolation

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.InterpolationUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.InterpolationUIFigure)
        end
    end
end